classdef CovidData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StateorRegionsearchboxLabel  matlab.ui.control.Label
        CountrysearchboxLabel        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        DateDatePicker               matlab.ui.control.DatePicker
        DateLabel                    matlab.ui.control.Label
        EditField_2                  matlab.ui.control.EditField
        EditField                    matlab.ui.control.EditField
        OptionButtonGroup            matlab.ui.container.ButtonGroup
        DailyButton                  matlab.ui.control.RadioButton
        CumulativeButton             matlab.ui.control.RadioButton
        DatatoPlotButtonGroup        matlab.ui.container.ButtonGroup
        BothButton                   matlab.ui.control.RadioButton
        DeathsButton                 matlab.ui.control.RadioButton
        CasesButton                  matlab.ui.control.RadioButton
        AveragedofdaysSlider         matlab.ui.control.Slider
        AveragedofdaysSliderLabel    matlab.ui.control.Label
        StateorRegionListBox         matlab.ui.control.ListBox
        StateorRegionListBoxLabel    matlab.ui.control.Label
        CountryListBox               matlab.ui.control.ListBox
        CountryListBoxLabel          matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Global                      % global data
        countryNames                % names of countries
        Dates                       % collected data dates
        Countries                   % countries data
        cIdx = 1                    % index of the last item clicked on CountryListBox
        sIdx = []                   % index of the last item clicked on StateorRegionListBox 
        strNumber = 'Cumulative'    % displays 'Cumulative'/'Daily' number
        strObj = 'Cases'            % displays 'Cases'/'Deaths'/'Cases and Deaths
        strCountry = 'Globally'     % displays 'Globally'/'in (Country name)'
        strMean = []                % displays the number of days to be averaged                
        dataPlot                    % data to be plotted on the axes
        sliderValue = 1             % stores the current value of the averaging slider
        cSearchValue = []           % value of the countries search bar
        sSearchValue = []           % value of the state or region search bar
        DateValue                   % store the current value of the date picker
        atTitle                     % store the current title for the axes and the text area
        allowPrint = 0              % flag for printout
    end
    
    methods (Access = private)
        function setDefaults(app)
            % properties assignment
            load FinalProject.mat global_var v_countries dates country_names
            app.Global = global_var;
            app.Countries = v_countries;
            app.countryNames = country_names;
            app.Dates = dates;
            
            % list box and axes initiation
            app.CountryListBox.Items(2:196) = app.countryNames;
            grid(app.UIAxes,'on');
            app.countryAssgn(app.cIdx-1,[]);
            app.dataAssgn(app.cIdx-1,[]);
            app.plotData();
            xlim(app.UIAxes,[app.Dates(1),app.Dates(end)]);
            app.labelTicksY();
        end

        function countryAssgn(app,c,s)
            if isempty(s)
                switch c
                    case 0
                        app.strCountry = 'Globally';
                    otherwise
                        app.strCountry = sprintf('in %s',app.Countries(c).countryName);
                end
            else
                switch s
                    case 0
                        app.strCountry = sprintf('in %s',app.Countries(c).countryName);
                    otherwise
                        app.strCountry = sprintf('in %s',app.Countries(c).States(s).States);
                end
            end
        end

        function dataAssgn(app,c,s)
            if isempty(s)
                switch c
                    case 0
                        switch app.strNumber
                            case 'Cumulative'
                                switch app.strObj
                                    case 'Cases'
                                        app.dataPlot = app.Global.casesCount;
                                    case 'Deaths'
                                        app.dataPlot = app.Global.deathsCount;
                                    case 'Cases and Deaths'
                                        app.dataPlot = app.Global.casesCount;
                                        app.dataPlot(2,:) = app.Global.deathsCount;
                                end
                            case 'Daily'
                                switch app.strObj
                                    case 'Cases'
                                        app.dataPlot = app.Global.casesCountD;
                                    case 'Deaths'
                                        app.dataPlot = app.Global.deathsCountD;
                                    case 'Cases and Deaths'
                                        app.dataPlot = app.Global.casesCountD;
                                        app.dataPlot(2,:) = app.Global.deathsCountD;
                                end
                        end
                    otherwise
                        switch app.strNumber
                            case 'Cumulative'
                                switch app.strObj
                                    case 'Cases'
                                        app.dataPlot = app.Countries(c).casesCount;
                                    case 'Deaths'
                                        app.dataPlot = app.Countries(c).deathsCount;
                                    case 'Cases and Deaths'
                                        app.dataPlot = app.Countries(c).casesCount;
                                        app.dataPlot(2,:) = app.Countries(c).deathsCount;
                                end
                            case 'Daily'
                                switch app.strObj
                                    case 'Cases'
                                        app.dataPlot = app.Countries(c).casesCountD;
                                    case 'Deaths'
                                        app.dataPlot = app.Countries(c).deathsCountD;
                                    case 'Cases and Deaths'
                                        app.dataPlot = app.Countries(c).casesCountD;
                                        app.dataPlot(2,:) = app.Countries(c).deathsCountD;
                                end
                        end
                end
            else
                switch s
                    case 0
                        dataAssgn(app,c,[]);
                    otherwise
                        switch app.strNumber
                            case 'Cumulative'
                                switch app.strObj
                                    case 'Cases'
                                        app.dataPlot = app.Countries(c).States(s).casesCount;
                                    case 'Deaths'
                                        app.dataPlot = app.Countries(c).States(s).deathsCount;
                                    case 'Cases and Deaths'
                                        app.dataPlot = app.Countries(c).States(s).casesCount;
                                        app.dataPlot(2,:) = app.Countries(c).States(s).deathsCount;
                                end
                            case 'Daily'
                                switch app.strObj
                                    case 'Cases'
                                        app.dataPlot = app.Countries(c).States(s).casesCountD;
                                    case 'Deaths'
                                        app.dataPlot = app.Countries(c).States(s).deathsCountD;
                                    case 'Cases and Deaths'
                                        app.dataPlot = app.Countries(c).States(s).casesCountD;
                                        app.dataPlot(2,:) = app.Countries(c).States(s).deathsCountD;
                                end
                        end
                end
            end
        end 
            
         function plotData(app)
            
            if app.sliderValue ~= 1
                app.strMean = sprintf('(%d-day mean)',app.sliderValue);
                for ii = 1:size(app.dataPlot,1)
                    app.dataPlot(ii,:) = movmean(app.dataPlot(ii,:),app.sliderValue);
                end
            else
                app.strMean = [];
            end
            
            switch app.strObj
                case 'Cases'
                    bar(app.UIAxes,app.Dates,app.dataPlot);
                    xlim(app.UIAxes,[app.Dates(1), app.Dates(end)]);
                    grid(app.UIAxes,"on");
                    app.labelTicksY();
                case 'Deaths'
                    plot(app.UIAxes,app.Dates,app.dataPlot,'Color',[0.8500, 0.3250, 0.0980]);
                    xlim(app.UIAxes,[app.Dates(1), app.Dates(end)]);
                    grid(app.UIAxes,"on");
                    app.labelTicksY();
                case 'Cases and Deaths'
                    yyaxis(app.UIAxes,'left');
                    bar(app.UIAxes,app.Dates,app.dataPlot(1,:),'FaceColor',[0, 0.4470, 0.7410]);
                    app.UIAxes.YColor = [0, 0.4470, 0.7410];
                    app.labelTicksY();
                    yyaxis(app.UIAxes,'right');
                    plot(app.UIAxes,app.Dates,app.dataPlot(2,:));
                    app.labelTicksY();
            end
            app.atTitle = sprintf('%s Number of %s %s %s',app.strNumber,app.strObj,app.strCountry,app.strMean);
            title(app.UIAxes,app.atTitle);
            ylabel(app.UIAxes,[]);
            xlabel(app.UIAxes,[]);
            app.UIAxes.Box = 'off';
         end

        function labelTicksY(app)
            yticks(app.UIAxes,'auto')
            yticklabels(app.UIAxes,sprintf('%.0f\n',app.UIAxes.YTick));
        end

        function resetSlider(app)
            app.AveragedofdaysSlider.Value = 1;
            app.sliderValue = 1;
        end

        function resetSearch(app)
            app.EditField.Value = '';
        end

        function searchC(app)
            matchList = {};
            if ~isempty(strfind(app.Global.countryName,app.cSearchValue))
                    matchList{end+1} = app.Global.countryName;
            end
            for ii = 1:length(app.countryNames)
                if ~isempty(strfind(app.countryNames{ii},app.cSearchValue))
                    matchList{end+1} = app.countryNames{ii};
                end
            end
            app.CountryListBox.Items = matchList;            
        end

        function searchS(app)
            matchList = {};
            if app.cIdx ~= 1
                if ~isempty(app.Countries(app.cIdx-1).statesNames)
                    if ~isempty(strfind('All',app.sSearchValue))
                        matchList{end+1} = 'All';
                    end
                    for ii = 2:length(app.Countries(app.cIdx-1).statesNames)
                        if ~isempty(strfind(app.Countries(app.cIdx-1).statesNames{ii},app.sSearchValue))
                            matchList{end+1} = app.Countries(app.cIdx-1).statesNames{ii};
                        end
                    end
                    app.StateorRegionListBox.Items = matchList;
                end
            end
        end  

        function CLBAssgn(app)
            CLBItems = app.CountryListBox.Items;
            for ii = 1:length(app.countryNames)
                if isequal(CLBItems(app.cIdx),app.countryNames(ii))
                    app.cIdx = ii+1;
                    return;
                end
            end
            app.cIdx = 1;
        end
                    
        function SORLBAssgn(app)
            SORLBItems = app.StateorRegionListBox.Items;
            if app.cIdx ~= 1
                for ii = 1:length(app.Countries(app.cIdx-1).statesNames)
                    if isequal(SORLBItems(app.sIdx),app.Countries(app.cIdx-1).statesNames(ii))
                        app.sIdx = ii;
                        return;
                    end
                end
            end
            app.sIdx = [];
        end
     
        function textPrintout(app)
            for ii = 1:707
                if isequal(app.DateValue,app.Dates(ii))
                    break;
                end
            end
            if size(app.dataPlot,1) == 1
                app.TextArea.Value = sprintf('%s: %.0f',app.atTitle,app.dataPlot(ii));
            else
                app.TextArea.Value = sprintf('%s:\nCases: %.0f\nDeaths: %.0f',app.atTitle,app.dataPlot(1,ii),app.dataPlot(2,ii));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    
            setDefaults(app);
        
        end

        % Clicked callback: CountryListBox
        function CountryListBoxClicked(app, event)
            
            app.cIdx = event.InteractionInformation.Item;      

            if length(app.Countries)+1 ~= length(app.CountryListBox.Items)
                app.CLBAssgn();
                app.CountryListBox.Items(1) = {'Global'};
                app.CountryListBox.Items(2:196) = app.countryNames;
                app.CountryListBox.Value = app.CountryListBox.Items{app.cIdx};
                if app.cIdx ~= 1
                    scroll(app.CountryListBox,app.countryNames{app.cIdx-1});
                end
            end
          
            if app.cIdx ~= 1
                if ~isempty(app.Countries(app.cIdx-1).statesNames)
                    app.StateorRegionListBox.Items(1:length(app.Countries(app.cIdx-1).statesNames)) = app.Countries(app.cIdx-1).statesNames;
                else
                    app.StateorRegionListBox.Items = {'All'};
                end
            else
                app.StateorRegionListBox.Items = {'All'};
            end
            app.sIdx = [];
            
            app.countryAssgn(app.cIdx-1,[]);
            app.dataAssgn(app.cIdx-1,[]);      
            app.resetSlider();
            app.EditField.Value = '';
            app.plotData();
            app.TextArea.Value = '';
            app.allowPrint = 0;

        end

        % Clicked callback: StateorRegionListBox
        function StateorRegionListBoxClicked(app, event)
            
            app.sIdx = event.InteractionInformation.Item; 
            
            if app.cIdx ~= 1
                if length(app.Countries(app.cIdx-1).States)+1 ~= length(app.StateorRegionListBox.Items)
                    app.SORLBAssgn();
                    app.StateorRegionListBox.Items = app.Countries(app.cIdx-1).statesNames;
                    app.StateorRegionListBox.Value = app.StateorRegionListBox.Items{app.sIdx};
                    scroll(app.StateorRegionListBox,app.Countries(app.cIdx-1).statesNames{app.sIdx});           
                end
            end

            app.countryAssgn(app.cIdx-1,app.sIdx-1);
            app.dataAssgn(app.cIdx-1,app.sIdx-1);
            app.resetSlider();
            app.EditField_2.Value = '';
            app.plotData();
            app.TextArea.Value = '';
            app.allowPrint = 0;

        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
                       
            if app.CasesButton.Value
                app.strObj = 'Cases';
            elseif app.DeathsButton.Value
                app.strObj = 'Deaths';
            elseif app.BothButton.Value
                app.strObj = 'Cases and Deaths';
            end
            if isequal(app.strObj,'Cases') || isequal(app.strObj,'Deaths')
                cla(app.UIAxes,'reset');
            end
            app.dataAssgn(app.cIdx-1,app.sIdx-1);
            app.resetSlider();
            app.plotData();
            if app.allowPrint, app.textPrintout(); end

        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            
            if app.CumulativeButton.Value
                app.strNumber = 'Cumulative';
            elseif app.DailyButton.Value    
                app.strNumber = 'Daily';
            end
            app.dataAssgn(app.cIdx-1,app.sIdx-1);
            app.plotData();
            if app.allowPrint, app.textPrintout(); end
            
        end

        % Value changed function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanged(app, event)
            
            app.AveragedofdaysSlider.Value = round(app.AveragedofdaysSlider.Value);
            app.sliderValue = app.AveragedofdaysSlider.Value;
            app.dataAssgn(app.cIdx-1,app.sIdx-1);
            app.plotData();
            if app.allowPrint, app.textPrintout(); end
            
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)

            app.EditField.Value = event.Value;
            app.cSearchValue = app.EditField.Value;
            app.searchC();
            
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            
            app.EditField_2.Value = event.Value;
            app.sSearchValue = app.EditField_2.Value;
            app.searchS();
            
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            
            app.DateValue = datetime(app.DateDatePicker.Value);
            app.allowPrint = 1;
            app.textPrintout();
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'Global'};
            app.CountryListBox.ClickedFcn = createCallbackFcn(app, @CountryListBoxClicked, true);
            app.CountryListBox.Value = 'Global';
            app.StateorRegionListBoxLabel = uilabel(app.UIFigure);
            app.StateorRegionListBoxLabel.Text = 'State or Region: ';
            app.StateorRegionListBox = uilistbox(app.UIFigure);
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.ClickedFcn = createCallbackFcn(app, @StateorRegionListBoxClicked, true);
            app.StateorRegionListBox.Value = 'All';
            app.AveragedofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 30];
            app.AveragedofdaysSlider.MajorTicks = [1 3 6 9 12 15 18 21 24 27 30];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanged, true);
            app.AveragedofdaysSlider.MinorTicks = [2 4 5 7 8 10 11 13 14 16 17 19 20 22 23 25 26 28 29];
            app.AveragedofdaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.DateLabel = uilabel(app.UIFigure);
            app.DateLabel.Text = 'Date:';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.DateDatePicker.Limits = [datetime([2020 1 22]) datetime([2021 12 28])];
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DateDatePicker.Value = datetime([2020 1 23]);
            app.TextArea = uitextarea(app.UIFigure);
            app.CountrysearchboxLabel = uilabel(app.UIFigure);
            app.CountrysearchboxLabel.Text = 'Country: search box';
            app.StateorRegionsearchboxLabel = uilabel(app.UIFigure);
            app.StateorRegionsearchboxLabel.Text = 'State or Region : search box';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end